classdef AIndexTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        TabGroup                  matlab.ui.container.TabGroup
        Tab                       matlab.ui.container.Tab
        GridLayout3               matlab.ui.container.GridLayout
        btn_LoadConfig            matlab.ui.control.Button
        pre_name                  matlab.ui.control.TextArea
        Label_2                   matlab.ui.control.Label
        btn_SaveImg               matlab.ui.control.Button
        btn_LoadImg               matlab.ui.control.Button
        Panel                     matlab.ui.container.Panel
        GridLayout4               matlab.ui.container.GridLayout
        mmLabel_6                 matlab.ui.control.Label
        Edit_pixelL               matlab.ui.control.NumericEditField
        EditField_2Label          matlab.ui.control.Label
        mmLabel_5                 matlab.ui.control.Label
        mmLabel_4                 matlab.ui.control.Label
        mmLabel_3                 matlab.ui.control.Label
        mmLabel_2                 matlab.ui.control.Label
        EditFieldLabel            matlab.ui.control.Label
        Edit_Mratio               matlab.ui.control.NumericEditField
        DdEditFieldLabel          matlab.ui.control.Label
        Edit_Dd                   matlab.ui.control.NumericEditField
        dEditFieldLabel           matlab.ui.control.Label
        Edit_d                    matlab.ui.control.NumericEditField
        cEditFieldLabel           matlab.ui.control.Label
        Edit_c                    matlab.ui.control.NumericEditField
        bEditFieldLabel           matlab.ui.control.Label
        Edit_b                    matlab.ui.control.NumericEditField
        mmLabel                   matlab.ui.control.Label
        Edit_a                    matlab.ui.control.NumericEditField
        aEditFieldLabel           matlab.ui.control.Label
        Drop_config               matlab.ui.control.DropDown
        Label                     matlab.ui.control.Label
        Tab_5                     matlab.ui.container.Tab
        GridLayout6               matlab.ui.container.GridLayout
        Panel_5                   matlab.ui.container.Panel
        GridLayout9               matlab.ui.container.GridLayout
        s_plot                    matlab.ui.control.Switch
        LSFCheckBox               matlab.ui.control.CheckBox
        d2PCheckBox               matlab.ui.control.CheckBox
        dPCheckBox                matlab.ui.control.CheckBox
        MTFCheckBox               matlab.ui.control.CheckBox
        ESFCheckBox               matlab.ui.control.CheckBox
        SCheckBox                 matlab.ui.control.CheckBox
        PCheckBox                 matlab.ui.control.CheckBox
        btn_selectROI             matlab.ui.control.Button
        btn_SaveData              matlab.ui.control.Button
        btn_indexcal              matlab.ui.control.Button
        Panel_3                   matlab.ui.container.Panel
        GridLayout8               matlab.ui.container.GridLayout
        TextArea                  matlab.ui.control.TextArea
        ROIListBox                matlab.ui.control.ListBox
        Panel_4                   matlab.ui.container.Panel
        GridLayout5               matlab.ui.container.GridLayout
        DeconvMethod              matlab.ui.container.ButtonGroup
        B4                        matlab.ui.control.RadioButton
        B3                        matlab.ui.control.RadioButton
        B2                        matlab.ui.control.RadioButton
        B1                        matlab.ui.control.RadioButton
        IndexType                 matlab.ui.container.ButtonGroup
        MTFButton                 matlab.ui.control.RadioButton
        CNIButton                 matlab.ui.control.RadioButton
        Panel_2                   matlab.ui.container.Panel
        GridLayout7               matlab.ui.container.GridLayout
        mmLabel_8                 matlab.ui.control.Label
        Edit_normL                matlab.ui.control.NumericEditField
        EditField_4Label          matlab.ui.control.Label
        Edit_tanL                 matlab.ui.control.NumericEditField
        EditField_3Label          matlab.ui.control.Label
        mmL                       matlab.ui.control.Label
        Tab_2                     matlab.ui.container.Tab
        GridLayout2               matlab.ui.container.GridLayout
        btn_SaveImg1              matlab.ui.control.Button
        psf1                      matlab.ui.container.Panel
        Switch1                   matlab.ui.control.Switch
        btn_indexcal1             matlab.ui.control.Button
        btn_m1start               matlab.ui.control.Button
        DropDown_3                matlab.ui.control.DropDown
        DropDown_3Label           matlab.ui.control.Label
        edit_denoise1             matlab.ui.control.NumericEditField
        EditField_7Label          matlab.ui.control.Label
        edit_kersize1             matlab.ui.control.NumericEditField
        EditField_6Label          matlab.ui.control.Label
        EditField_5Label          matlab.ui.control.Label
        edit_psfr1                matlab.ui.control.NumericEditField
        DropDown_2Label           matlab.ui.control.Label
        drop_psftype1             matlab.ui.control.DropDown
        functiondeconvBlindLabel  matlab.ui.control.Label
        Tab_3                     matlab.ui.container.Tab
        GridLayout2_2             matlab.ui.container.GridLayout
        Switch_2                  matlab.ui.control.Switch
        functiondeconvRegLabel    matlab.ui.control.Label
        Tab_4                     matlab.ui.container.Tab
        GridLayout2_3             matlab.ui.container.GridLayout
        Switch_3                  matlab.ui.control.Switch
        functiondeconvLucyLabel   matlab.ui.control.Label
        RightPanel                matlab.ui.container.Panel
        btn_open                  matlab.ui.control.Button
        btn_close                 matlab.ui.control.Button
        AcceptanceIndexToolv03CopyrightSONTULabel  matlab.ui.control.Label
        UITable                   matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        I_orig = []; % the original Phantom dicom Image
        I_b1 = [];
        I_b2 = [];

        metaData = []; % the metaData of the original dicom image

        main_axes;
        psf_axes1;

        param = funIniParam(); % parameters
        h_roi_cni;
        h_roi_mtf;
        h_num_cni;
        h_num_mtf;

        points_cni;
        points_mtf;

        data_out = {};
        data_num;
        table_out;
        pdlg;

        cnidata = [];
        mtfdata = [];

        p_num = 0;
        p_index = [];
        release = 1;
        fp = [100 100 400 300];
    end

    methods (Access = private)

        function [] = funConfigRes(app)
            v = app.Drop_config.Value;
            s = app.Drop_config.Items;
            for i = 1:length(s)
                if(strcmp(v,s{i}))
                    break
                end
            end

            app.Drop_config.UserData = i;

            app.Edit_a.Value = app.param.config(i).a;
            app.Edit_b.Value = app.param.config(i).b;
            app.Edit_c.Value = app.param.config(i).c;
            app.Edit_d.Value = app.param.config(i).d;
            app.Edit_Dd.Value = app.param.config(i).Dd;
            app.Edit_Mratio.Value = app.param.config(i).Mratio;
            app.Edit_pixelL.Value = app.param.config(i).pixelL;
        end

        function [] = funROIParamRes(app)
            i = app.DeconvMethod.SelectedObject.UserData;

            if strcmp(app.IndexType.SelectedObject.Text,'MTF')
                app.Edit_tanL.Value = app.param.roi(i).tanLMTF;
                app.Edit_normL.Value = app.param.roi(i).normLMTF;
            end

            if strcmp(app.IndexType.SelectedObject.Text,'CNI')
                app.Edit_tanL.Value = app.param.roi(i).tanLCNI;
                app.Edit_normL.Value = app.param.roi(i).normLCNI;
            end
        end

        function [X,Y,cpoint,HVmode] = funPointTrans(app,p1,p2)
            pixelL = app.Edit_pixelL.Value;
            tanL = app.Edit_tanL.Value;
            normL = app.Edit_normL.Value;

            tanLP = round(tanL/pixelL/2);
            normP = round(normL/pixelL);


            hdist = abs(p1(1) - p2(1));
            vdist = abs(p1(2) - p2(2));
            cpoint = round([(p1(1)+p2(1))/2, (p1(2)+p2(2))/2]);

            if hdist>vdist%horizontal edge
                HVmode = 2;
                X = [cpoint(1)-tanLP, cpoint(2)-normP];
                Y = [cpoint(1)+tanLP, cpoint(2)+normP];
            end

            if hdist<vdist%vertical edge
                HVmode = 1;
                X = [cpoint(1)-normP, cpoint(2)-tanLP];
                Y = [cpoint(1)+normP, cpoint(2)+tanLP];
            end
        end

        function [rois,num] = funDrawRegion(app,points,rois,num)
            for i = 1:4

                X = points(i).X;
                Y = points(i).Y;
                C = points(i).C;
                if ~points(i).empty
                    rois(i) = rectangle('Position',[X(1) X(2) Y(1)-X(1) Y(2)-X(2)],...
                        "EdgeColor",'r',...
                        'Parent',app.main_axes);
                    num(i) = text(app.main_axes,C(1),C(2),num2str(i),'Color','r');
                end

            end
        end

        function [] = funGetData_0(app)
            I = double(app.I_orig);
            pixelL = app.Edit_pixelL.Value;
            normLCNI = app.param.roi(4).normLCNI;
            index = app.Drop_config.UserData;
            p = app.param.config(index);
            prename = app.pre_name.Value{:};

            app.cnidata = [];
            app.mtfdata = [];
            for i = 1:4
                if app.release
                    app.pdlg.Value = .66/(5-i);
                end
                cni = nan;ai = nan;
                v50 = nan;v10 = nan;

                if ~app.points_cni(i).empty
                    X = app.points_cni(i).X;
                    Y = app.points_cni(i).Y;
                    IroiCNI = I(X(2):Y(2),X(1):Y(1));
                    HVmode = app.points_cni(i).HVmode;
                    [cni, data_cni] = funCTCNI(IroiCNI,pixelL,normLCNI,HVmode);
                end

                if ~app.points_cni(i).empty
                    X = app.points_mtf(i).X;
                    Y = app.points_mtf(i).Y;
                    IroiMTF = I(X(2):Y(2),X(1):Y(1));
                    HVmode = app.points_mtf(i).HVmode;
                    [v50, v10, data_mtf] = funCTMTF(IroiMTF,pixelL,HVmode);
                end

                if (~isnan(cni)||~isnan(v50))
                    ai = funCTAI(p, v50, cni);
                end

                data_tmp = {[prename,newline,'-原始-区域',num2str(i)],  ai, v50, v10, cni};
                app.data_out(i,:)  = data_tmp;
                app.cnidata = [app.cnidata; data_cni];
                app.mtfdata = [app.mtfdata; data_mtf];
                app.data_num = 4;
            end
        end

        function [] = funGetData_1(app)
            I = double(app.I_b2);
            pixelL = app.Edit_pixelL.Value;
            normLCNI = app.param.roi(1).normLCNI;
            index = app.Drop_config.UserData;
            p = app.param.config(index);
            prename = app.pre_name.Value{:};

            if app.release
                pv = app.pdlg.Value;
            end
            data_cni = [];
            data_mtf = [];
            for i = 1:4
                if app.release
                    app.pdlg.Value = pv +.2/(5-i);
                end
                cni = nan;ai = nan;
                v50 = nan;v10 = nan;


                if ~app.points_cni(i).empty
                    X = app.points_cni(i).X;
                    Y = app.points_cni(i).Y;
                    IroiCNI = I(X(2):Y(2),X(1):Y(1));
                    HVmode = app.points_cni(i).HVmode;
                    [cni, data_cni] = funCTCNI(IroiCNI,pixelL,normLCNI,HVmode);
                end

                if ~app.points_cni(i).empty
                    X = app.points_mtf(i).X;
                    Y = app.points_mtf(i).Y;
                    IroiMTF = I(X(2):Y(2),X(1):Y(1));
                    HVmode = app.points_mtf(i).HVmode;
                    [v50, v10, data_mtf] = funCTMTF(IroiMTF,pixelL,HVmode);
                end

                if (~isnan(cni)||~isnan(v50))
                    ai = funCTAI(p, v50, cni);
                end

                data_tmp = {[prename,newline,'-M1-区域',num2str(i)],  ai, v50, v10, cni};
                app.data_out = [app.data_out;data_tmp];
                app.cnidata = [app.cnidata; data_cni];
                app.mtfdata = [app.mtfdata; data_mtf];
                app.data_num = 8;
            end
        end

        function plot_i = funPlotCNIP(app,i)
            %CNI P
            plot_i = 10+i;
            h = figure(plot_i);
            h.Position = [app.fp(1) app.fp(2) app.fp(3) app.fp(4)];
            title('CNI Mean');hold on;
            xlabel('pixel')
            leg_str = {[]};
            xm = [0. 0.];
            pm = [0. 0.];
            for k = 0:(app.data_num/4-1)
                plot(app.cnidata(k*4+i).P);
                leg_str{3*k+1} = [app.data_out{k*4+i,1}];
                leg_str{3*k+2} = [app.data_out{k*4+i,1},'-Min'];
                leg_str{3*k+3} = [app.data_out{k*4+i,1},'-Max'];
                xm(1) = app.cnidata(k*4+i).xmax;
                xm(2) = app.cnidata(k*4+i).xmin;
                pm(1) = app.cnidata(k*4+i).P(xm(1));
                pm(2) = app.cnidata(k*4+i).P(xm(2));
                plot(xm(1),pm(1),'o','LineWidth',2);
                text(xm(1),pm(1),num2str(pm(1)));
                plot(xm(2),pm(2),'o','LineWidth',2);
                text(xm(2),pm(2),num2str(pm(2)));
            end
            grid on
            box on
            axis tight;
            legend(leg_str,'Location','eastoutside','Orientation',"vertical");
        end

        function plot_i = funPlotCNIS(app,i)
            %CNI S
            plot_i = 20+i;
            h = figure(plot_i);
            h.Position = [app.fp(1) app.fp(2) app.fp(3) app.fp(4)];
            title('CNI Std');hold on;
            xlabel('pixel')
            leg_str = {[]};
            xm = [0. 0.];
            sm = [0. 0.];
            for k = 0:(app.data_num/4-1)
                plot(app.cnidata(k*4+i).S);
                leg_str{3*k+1} = [app.data_out{k*4+i,1}];
                leg_str{3*k+2} = [app.data_out{k*4+i,1},'-Min'];
                leg_str{3*k+3} = [app.data_out{k*4+i,1},'-Max'];
                xm(1) = app.cnidata(k*4+i).xmax;
                xm(2) = app.cnidata(k*4+i).xmin;
                sm(1) = app.cnidata(k*4+i).S(xm(1));
                sm(2) = app.cnidata(k*4+i).S(xm(2));
                plot(xm(1),sm(1),'o','LineWidth',2);
                text(xm(1),sm(1),num2str(sm(1)));
                plot(xm(2),sm(2),'o','LineWidth',2);
                text(xm(2),sm(2),num2str(sm(2)));
            end
            grid on
            box on
            axis tight;
            legend(leg_str,'Location','eastoutside');
        end

        function plot_i = funPlotCNIdP(app,i)
            %CNI dP
            plot_i= 30+i;
            h = figure(plot_i);
            h.Position = [app.fp(1) app.fp(2) app.fp(3) app.fp(4)];
            title('CNI first order difference');hold on;
            xlabel('pixel')
            leg_str = {[]};
            for k = 0:(app.data_num/4-1)
                plot(app.cnidata(k*4+i).dP);
                leg_str{k+1} = [app.data_out{k*4+i,1}];
            end
            grid on
            box on
            axis tight;
            legend(leg_str,'Location','eastoutside');
        end

        function plot_i = funPlotCNId2P(app,i)
            %CNI d2P
            plot_i = 40+i;
            h = figure(plot_i);
            h.Position = [app.fp(1) app.fp(2) app.fp(3) app.fp(4)];
            title('CNI second order difference');hold on;
            xlabel('pixel')
            leg_str = {[]};
            for k = 0:(app.data_num/4-1)
                plot(app.cnidata(k*4+i).d2P);
                leg_str{k+1} = [app.data_out{k*4+i,1}];
            end
            grid on
            box on
            axis tight;
            legend(leg_str,'Location','eastoutside');
        end

        function plot_i = funPlotMTFESF(app,i)
            %MTF R
            plot_i = 50+i;
            h = figure(plot_i);
            h.Position = [app.fp(1) app.fp(2) app.fp(3) app.fp(4)];
            title('MTF edge spread function');hold on;
            xlabel('length(mm)');
            leg_str = {[]};
            for k = 0:(app.data_num/4-1)
                plot(app.mtfdata(k*4+i).xp,app.mtfdata(k*4+i).R);
                leg_str{k+1} = [app.data_out{k*4+i,1}];
            end
            grid on
            box on
            axis tight;
            legend(leg_str,'Location','eastoutside');
        end

        function plot_i = funPlotMTFLSF(app,i)
            %MTF dR
            plot_i = 60+i;
            h = figure(plot_i);
            h.Position = [app.fp(1) app.fp(2) app.fp(3) app.fp(4)];
            title('MTF line spread function');hold on;
            xlabel('length(mm)');
            leg_str = {[]};
            for k = 0:(app.data_num/4-1)
                plot(app.mtfdata(k*4+i).dx,app.mtfdata(k*4+i).dR);
                leg_str{k+1} = [app.data_out{k*4+i,1}];
            end
            grid on
            box on
            axis tight;
            legend(leg_str,'Location','eastoutside');
        end

        function plot_i = funPlotMTF(app,i)
            %MTF
            plot_i = 70+i;
            h = figure(plot_i);
            h.Position = [app.fp(1) app.fp(2) app.fp(3) app.fp(4)];
            title('MTF Modulation transfer function');hold on;
            xlabel('frequncy(LP/mm)');
            leg_str = {[]};
            for k = 0:(app.data_num/4-1)

                tmpf = [app.data_out{k*4+i,3};app.data_out{k*4+i,4}];
                plot(app.mtfdata(k*4+i).fx,app.mtfdata(k*4+i).MTF);
                leg_str{3*k+1} = [app.data_out{k*4+i,1}];
                leg_str{3*k+2} = [app.data_out{k*4+i,1},'-MTF-V50'];
                leg_str{3*k+3} = [app.data_out{k*4+i,1},'-MTF-V10'];
                plot(tmpf(1),0.5,'o','LineWidth',2);
                plot(tmpf(2),0.1,'o','LineWidth',2);
                text(tmpf(1),0.5+k*0.05,num2str(tmpf(1)));
                text(tmpf(2),0.1+k*0.05,num2str(tmpf(2)));
            end
            grid on
            box on
            axis tight;
            grid minor
            legend(leg_str,'Location','eastoutside');
        end

        function [] = funPlotService(app)

            if strcmp(app.s_plot.Value, '开启')
                for i = 1:app.p_num
                    close(figure(app.p_index(i)));
                end
                app.p_num = 0;
                app.p_index = [];
                for i = 1:4
                    if ~(app.points_cni(i).empty||app.points_cni(i).empty)

                        %P
                        if app.PCheckBox.Value == 1
                            pindex = app.funPlotCNIP(i);
                            app.p_index= [app.p_index;pindex];
                            app.p_num = app.p_num+1;
                        end
                        %S
                        if app.SCheckBox.Value == 1
                            pindex = app.funPlotCNIS(i);
                            app.p_index= [app.p_index;pindex];
                            app.p_num = app.p_num+1;
                        end
                        %dP
                        if app.dPCheckBox.Value == 1
                            pindex = app.funPlotCNIdP(i);
                            app.p_index= [app.p_index;pindex];
                            app.p_num = app.p_num+1;
                        end
                        %d2P
                        if app.d2PCheckBox.Value == 1
                            pindex = app.funPlotCNId2P(i);
                            app.p_index= [app.p_index;pindex];
                            app.p_num = app.p_num+1;
                        end
                        %ESF
                        if app.ESFCheckBox.Value == 1
                            pindex = app.funPlotMTFESF(i);
                            app.p_index= [app.p_index;pindex];
                            app.p_num = app.p_num+1;
                        end
                        %LSF
                        if app.LSFCheckBox.Value == 1
                            pindex = app.funPlotMTFLSF(i);
                            app.p_index= [app.p_index;pindex];
                            app.p_num = app.p_num+1;
                        end
                        %MTF
                        if app.MTFCheckBox.Value == 1
                            pindex = app.funPlotMTF(i);
                            app.p_index= [app.p_index;pindex];
                            app.p_num = app.p_num+1;
                        end

                    end
                end
            end

        end

        function [] = funSavePlot(app,filepath,sheetname)
            rbase = 16;
            cbase = 6;
            checki = [app.PCheckBox.Value;
                app.SCheckBox.Value;
                app.dPCheckBox.Value;
                app.d2PCheckBox.Value;
                app.ESFCheckBox.Value;
                app.LSFCheckBox.Value;
                app.MTFCheckBox.Value];

            coli = 0;
            for i = 1:7
                if ~checki(i)
                    continue;
                end
                colL = xlcolumnletter(coli*cbase+1);
                coli = coli+1;

                rowi = 0;
                for k = 1:4
                    if (app.points_cni(k).empty||app.points_mtf(k).empty)
                        continue;
                    end
                    rowL = num2str(rowi*rbase+1);
                    rowi = rowi+1;
                    [colL,rowL]
                    xlswritefig(figure(i*10+k), filepath, sheetname, [colL,rowL]);
                end
            end

        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.IndexType.UserData = 1;
            app.DeconvMethod.UserData = 1;
            app.ROIListBox.UserData = 1;

            app.B1.UserData = 1;
            app.B2.UserData = 2;
            app.B3.UserData = 3;
            app.B4.UserData = 4;

            app.funConfigRes();

            app.funROIParamRes();

            app.main_axes = axes('Parent',app.RightPanel,...
                'Position',[0,0,1,1],...
                'XTickLabel',[],...
                'YTickLabel',[],...
                'XTick',[],...
                'YTick',[],...
                'XColor',[1. 1. 1.],...
                'YColor',[1. 1. 1.]);

            app.h_roi_cni = repmat(rectangle('Parent',app.main_axes),[1,4]);
            app.h_roi_mtf = repmat(rectangle('Parent',app.main_axes),[1,4]);
            app.h_num_cni = repmat(text('Parent',app.main_axes),[1,4]);
            app.h_num_mtf = repmat(text('Parent',app.main_axes),[1,4]);

            delete(app.h_roi_cni);
            delete(app.h_roi_mtf);

            for i = 1:4
                app.points_cni(i).X = 0;
                app.points_cni(i).Y = 0;
                app.points_cni(i).C = 0;
                app.points_cni(i).empty = 1;
                app.points_mtf(i).X = 0;
                app.points_mtf(i).Y = 0;
                app.points_mtf(i).C = 0;
                app.points_mtf(i).empty = 1;
            end

        end

        % Button pushed function: btn_LoadImg
        function btn_LoadImgButtonPushed(app, event)
            [filename, pathname,index] = uigetfile({'*.dcm';'*.tif';'*.*'},'Select an dicom file');

            if (isequal(filename,0)||isequal(pathname,0))
                return
            end

            if index == 1
                app.I_orig = dicomread(fullfile(pathname,filename)) - 1000;
                app.metaData = dicominfo(fullfile(pathname,filename));
            end

            if index == 2
                app.I_orig = imread(fullfile(pathname,filename)) - 1000;
                app.metaData = [];
            end

            imshow(app.I_orig, [],'Parent',app.main_axes);
            %             figure(1);imshow(app.I_orig, []);

            app.btn_m1start.Enable = true;
            app.btn_SaveImg.Enable = true;
            app.btn_selectROI.Enable = true;
        end

        % Button pushed function: btn_selectROI
        function btn_selectROIPushed(app, event)

            %             if app.IndexType.UserData == 1%CNI
            %                 delete(app.h_roi_mtf);
            %                 app.funDrawRegion(app.points_cni,app.h_roi_cni);
            %             end
            %
            %             if app.IndexType.UserData == 2%MTF
            %                 delete(app.h_roi_cni);
            %                 app.funDrawRegion(app.points_mtf,app.h_roi_mtf);
            %             end

            roi_i = app.ROIListBox.UserData;

            Proi1 = images.roi.Point(app.main_axes);
            draw(Proi1);
            Proi2 = images.roi.Point(app.main_axes);
            draw(Proi2);

            baseP1 = Proi1.Position;
            baseP2 = Proi2.Position;

            delete(Proi1);
            delete(Proi2);

            [X,Y,C,HVmode] = app.funPointTrans(baseP1,baseP2);

            if app.IndexType.UserData == 1%CNI

                delete(app.h_roi_cni(roi_i));
                delete(app.h_num_cni(roi_i));

                app.points_cni(roi_i).X = X;
                app.points_cni(roi_i).Y = Y;
                app.points_cni(roi_i).C = C;
                app.points_cni(roi_i).empty = 0;
                app.points_cni(roi_i).HVmode = HVmode;

                app.h_roi_cni(roi_i) = rectangle('Position',[X(1) X(2) Y(1)-X(1) Y(2)-X(2)],...
                    "EdgeColor",'b',...
                    'Parent',app.main_axes);
                app.h_num_cni(roi_i) = text(app.main_axes,C(1),C(2),num2str(roi_i),'Color','b');
            end


            if app.IndexType.UserData == 2%MTF

                delete(app.h_roi_mtf(roi_i));
                delete(app.h_num_mtf(roi_i));

                app.points_mtf(roi_i).X = X;
                app.points_mtf(roi_i).Y = Y;
                app.points_mtf(roi_i).C = C;
                app.points_mtf(roi_i).empty = 0;
                app.points_mtf(roi_i).HVmode = HVmode;

                app.h_roi_mtf(roi_i) = rectangle('Position',[X(1) X(2) Y(1)-X(1) Y(2)-X(2)],...
                    "EdgeColor",'g',...
                    'Parent',app.main_axes);
                app.h_num_mtf(roi_i) = text(app.main_axes,C(1),C(2),num2str(roi_i),'Color','g');
            end

            app.btn_indexcal.Enable = true;
        end

        % Value changed function: Drop_config
        function Drop_configValueChanged(app, event)

            app.funConfigRes();

        end

        % Selection changed function: DeconvMethod
        function DeconvMethodSelectionChanged(app, event)
            app.funROIParamRes();

            i = app.DeconvMethod.SelectedObject.UserData;
            app.DeconvMethod.UserData = i;
            disp(i)

        end

        % Selection changed function: IndexType
        function IndexTypeSelectionChanged(app, event)
            app.funROIParamRes();

            if strcmp(app.IndexType.SelectedObject.Text,'MTF')
                app.IndexType.UserData = 2;
                delete(app.h_roi_cni(:));
                delete(app.h_num_cni(:));
                [app.h_roi_mtf,app.h_num_mtf] = app.funDrawRegion(app.points_mtf,app.h_roi_mtf,app.h_num_mtf);
            end

            if strcmp(app.IndexType.SelectedObject.Text,'CNI')
                app.IndexType.UserData = 1;
                delete(app.h_roi_mtf(:));
                delete(app.h_num_mtf(:));
                [app.h_roi_cni,app.h_num_cni] = app.funDrawRegion(app.points_cni,app.h_roi_cni,app.h_num_cni);
            end

            disp(app.IndexType.UserData);

        end

        % Value changed function: ROIListBox
        function ROIListBoxValueChanged(app, event)
            v = app.ROIListBox.Value;
            s = app.ROIListBox.Items;
            for i = 1:length(s)
                if(strcmp(v,s{i}))
                    break
                end
            end

            app.ROIListBox.UserData = i;
            disp(i)

        end

        % Button pushed function: btn_m1start
        function btn_m1startPushed(app, event)
            if app.release
                app.pdlg = uiprogressdlg(app.UIFigure,'Title','分辨率提升处理',...
                    'Message','请等待图像处理结束...');
            end

            app.psf_axes1 = axes('Parent',app.psf1,...
                'Position',[0,0,1,1],...
                'XTickLabel',[],...
                'YTickLabel',[],...
                'XTick',[],...
                'YTick',[],...
                'XColor',[1. 1. 1.],...
                'YColor',[1. 1. 1.]);

            I = double(app.I_orig);
            minI = min(I,[],'all');
            maxI = max(I,[],'all');
            I = (I-minI)/(maxI-minI);

            PSF = fspecial('gaussian',app.edit_kersize1.Value,...
                app.edit_psfr1.Value);

            imagesc(PSF,'Parent',app.psf_axes1);

            if app.release
                app.pdlg.Value = .50;
            end

            [app.I_b1, app.I_b2] = Bdeconv(I,PSF,[],2);

            app.I_b1 = app.I_b1*(maxI-minI) + minI;
            app.I_b2 = app.I_b2*(maxI-minI) + minI;
            app.I_b2 = int16(app.I_b2);
            imshow(app.I_b2, [],'Parent',app.main_axes);
            app.Switch1.Value = '处理图像';


            app.Switch1.Enable = true;
            app.btn_indexcal1.Enable = true;
            app.btn_SaveImg1.Enable = true;
            if app.release
                app.pdlg.Message = '完成.';
                close(app.pdlg);
            end
        end

        % Value changed function: Switch1
        function Switch1ValueChanged(app, event)
            value = app.Switch1.Value;

            if strcmp(value, '原始图像')
                imshow(app.I_orig, [],'Parent',app.main_axes);
            end

            if strcmp(value, '处理图像')
                imshow(app.I_b2, [],'Parent',app.main_axes);
            end

        end

        % Button pushed function: btn_indexcal
        function btn_indexcalPushed(app, event)

            if app.release
                app.pdlg = uiprogressdlg(app.UIFigure,'Title','计算AI指标',...
                    'Message','请等待计算结束...');
            end
            %initialize data_out/cnidata/mtfdata
            app.data_out = {};
            app.cnidata = [];
            app.mtfdata = [];

            %calculate the index data for originalimage
            app.funGetData_0();
            %show the index data in uitable
            app.UITable.Data = array2table(app.data_out);

            app.funPlotService();

            if app.release
                app.pdlg.Value = .90;
            end

            app.UITable.Visible = true;
            app.UITable.Enable = 'on';

            app.btn_SaveData.Enable = true;
            if app.release
                app.pdlg.Message = '完成.';
                close(app.pdlg);
            end

        end

        % Button pushed function: btn_close
        function btn_closeButtonPushed(app, event)
            app.UITable.Visible = false;
            app.UITable.Enable = 'off';
        end

        % Button pushed function: btn_open
        function btn_openButtonPushed(app, event)
            app.UITable.Visible = true;
            app.UITable.Enable = 'on';
        end

        % Button pushed function: btn_indexcal1
        function btn_indexcal1ButtonPushed(app, event)
            if app.release
                app.pdlg = uiprogressdlg(app.UIFigure,'Title','计算AI指标',...
                    'Message','请等待计算结束...');
            end
            app.data_out = {};
            app.funGetData_0();
            app.funGetData_1();
            app.UITable.Data = array2table(app.data_out);

            app.funPlotService();

            if app.release
                app.pdlg.Value = .90;
            end

            app.UITable.Visible = true;
            app.UITable.Enable = 'on';
            app.btn_SaveData.Enable = true;

            if app.release
                app.pdlg.Message = '完成.';
                close(app.pdlg);
            end
        end

        % Button pushed function: btn_SaveImg
        function btn_SaveImgButtonPushed(app, event)
            [path] = uigetdir('D:\');

            if isequal(path,0)
                return;
            end

            prename = app.pre_name.Value{:};

            if ~isempty(app.I_b2)
                I_tmp = app.I_b2 +1000;
                if isempty(app.metaData)
                    dicomwrite(I_tmp,fullfile(path,[prename,'_m1.dcm']));
                else
                    dicomwrite(I_tmp,fullfile(path,[prename,'_m1.dcm']),app.metaData);
                end
            end

            if ~isempty(app.I_orig)
                I_tmp = app.I_orig +1000;
                if isempty(app.metaData)
                    dicomwrite(I_tmp,fullfile(path,[prename,'_orig.dcm']));
                else
                    dicomwrite(I_tmp,fullfile(path,[prename,'_orig.dcm']),app.metaData);
                end

            end
        end

        % Button pushed function: btn_SaveImg1
        function btn_SaveImg1ButtonPushed(app, event)
            filter = {'*.dcm';'*.*'};
            [file, path] = uiputfile(filter);

            if isequal(file,0) || isequal(path,0)
                return
            end

            prename = app.pre_name.Value{:};
            dicomwrite(app.I_b2,fullfile(path,[prename,file]));
        end

        % Button pushed function: btn_SaveData
        function btn_SaveDataButtonPushed(app, event)
            filter = {'*.csv';'*.xlsx';'*.xls';'*.*'};
            [file, path,index] = uiputfile(filter);

            if isequal(file,0) || isequal(path,0)
                return
            end

            if app.release
                app.pdlg = uiprogressdlg(app.UIFigure,'Title','保存计算数据',...
                    'Message','请等待文件保存结束...');
            end
            
            prename = app.pre_name.Value{:};
            data_tmp = app.UITable.Data;
            data_tmp.Properties.VariableNames = app.UITable.ColumnName;

            if index == 1
                if app.release
                    app.pdlg.Message = '请等待CSV文件保存结束...';
                    app.pdlg.Value = 0.4;
                end
                writetable(data_tmp,fullfile(path,[prename,file]));
            end
            if index > 1
                if app.release
                    app.pdlg.Message = '请等待Excel文件保存结束...';
                    app.pdlg.Value = 0.4;
                end
                writetable(cell2table({'V0.3'},'VariableNames',{'Data Version'})...
                    ,fullfile(path,[prename,file]),'Sheet','Version');
                writetable(data_tmp,fullfile(path,[prename,file]),'Sheet','AcceptanceIndex');
                if strcmp(app.s_plot.Value,'开启')
                    if app.release
                        app.pdlg.Message = '请等待数据图像保存结束...';
                        app.pdlg.Value = 0.8;
                    end
                    filepath = fullfile(path,[prename,file]);
                    app.funSavePlot(filepath,'Figures');

                end
            end
            if app.release
                app.pdlg.Message = '完成.';
                app.pdlg.Value = 1;
                close(app.pdlg);
            end

        end

        % Button pushed function: btn_LoadConfig
        function btn_LoadConfigButtonPushed(app, event)

            [filename, pathname, index] = uigetfile({'*.xml';'*.*'},'Select an dicom file');

            if (isequal(filename,0)||isequal(pathname,0))
                return
            end

            app.param = xml_read(fullfile(pathname,filename)); % parameters
            app.funConfigRes();

        end

        % Value changed function: s_plot
        function s_plotValueChanged(app, event)
            value = app.s_plot.Value;

            if strcmp(value, '开启')
                app.PCheckBox.Enable = true;
                app.SCheckBox.Enable = true;
                app.dPCheckBox.Enable = true;
                app.d2PCheckBox.Enable = true;
                app.ESFCheckBox.Enable = true;
                app.LSFCheckBox.Enable = true;
                app.MTFCheckBox.Enable = true;
            end

            if strcmp(value, '关闭')
                for i = 1:app.p_num
                    close(figure(app.p_index(i)));
                end
                app.p_num = 0;
                app.p_index = [];
                app.PCheckBox.Enable = false;
                app.SCheckBox.Enable = false;
                app.dPCheckBox.Enable = false;
                app.d2PCheckBox.Enable = false;
                app.ESFCheckBox.Enable = false;
                app.LSFCheckBox.Enable = false;
                app.MTFCheckBox.Enable = false;
            end

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            close all
            delete(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {625, 625};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {236, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '配置';
            app.GridLayout3 = uigridlayout(app.Tab);
            app.Panel = uipanel(app.GridLayout3);
            app.Panel.Title = '参数';
            app.GridLayout4 = uigridlayout(app.Panel);
            app.Label = uilabel(app.GridLayout4);
            app.Label.Text = '视野模式';
            app.Drop_config = uidropdown(app.GridLayout4);
            app.Drop_config.Items = {'模式一', '模式二', '模式三'};
            app.Drop_config.ValueChangedFcn = createCallbackFcn(app, @Drop_configValueChanged, true);
            app.Drop_config.Value = '模式一';
            app.aEditFieldLabel = uilabel(app.GridLayout4);
            app.aEditFieldLabel.Text = 'a';
            app.Edit_a = uieditfield(app.GridLayout4, 'numeric');
            app.Edit_a.Value = 460;
            app.mmLabel = uilabel(app.GridLayout4);
            app.mmLabel.Text = 'mm';
            app.Edit_b = uieditfield(app.GridLayout4, 'numeric');
            app.Edit_b.Value = 650;
            app.bEditFieldLabel = uilabel(app.GridLayout4);
            app.bEditFieldLabel.Text = 'b';
            app.Edit_c = uieditfield(app.GridLayout4, 'numeric');
            app.Edit_c.Value = 196.73;
            app.cEditFieldLabel = uilabel(app.GridLayout4);
            app.cEditFieldLabel.Text = 'c';
            app.Edit_d = uieditfield(app.GridLayout4, 'numeric');
            app.Edit_d.Value = 278;
            app.dEditFieldLabel = uilabel(app.GridLayout4);
            app.dEditFieldLabel.Text = 'd';
            app.Edit_Dd = uieditfield(app.GridLayout4, 'numeric');
            app.Edit_Dd.Value = 9.2;
            app.DdEditFieldLabel = uilabel(app.GridLayout4);
            app.DdEditFieldLabel.Text = 'Dd';
            app.Edit_Mratio = uieditfield(app.GridLayout4, 'numeric');
            app.EditFieldLabel = uilabel(app.GridLayout4);
            app.EditFieldLabel.Text = '放大比';
            app.mmLabel_2 = uilabel(app.GridLayout4);
            app.mmLabel_2.Text = 'mm';
            app.mmLabel_3 = uilabel(app.GridLayout4);
            app.mmLabel_3.Text = 'mm';
            app.mmLabel_4 = uilabel(app.GridLayout4);
            app.mmLabel_4.Text = 'mm';
            app.mmLabel_5 = uilabel(app.GridLayout4);
            app.mmLabel_5.Text = 'mm';
            app.EditField_2Label = uilabel(app.GridLayout4);
            app.EditField_2Label.Text = '像素尺寸';
            app.Edit_pixelL = uieditfield(app.GridLayout4, 'numeric');
            app.Edit_pixelL.Value = 0.24;
            app.mmLabel_6 = uilabel(app.GridLayout4);
            app.mmLabel_6.Text = 'mm';
            app.btn_LoadImg = uibutton(app.GridLayout3, 'push');
            app.btn_LoadImg.ButtonPushedFcn = createCallbackFcn(app, @btn_LoadImgButtonPushed, true);
            app.btn_LoadImg.Text = '导入图像';
            app.btn_SaveImg = uibutton(app.GridLayout3, 'push');
            app.btn_SaveImg.ButtonPushedFcn = createCallbackFcn(app, @btn_SaveImgButtonPushed, true);
            app.btn_SaveImg.Enable = 'off';
            app.btn_SaveImg.Text = '保存所有图像';
            app.Label_2 = uilabel(app.GridLayout3);
            app.Label_2.Text = '配置备注';
            app.pre_name = uitextarea(app.GridLayout3);
            app.btn_LoadConfig = uibutton(app.GridLayout3, 'push');
            app.btn_LoadConfig.ButtonPushedFcn = createCallbackFcn(app, @btn_LoadConfigButtonPushed, true);
            app.btn_LoadConfig.Text = '导入配置';
            app.Tab_5 = uitab(app.TabGroup);
            app.Tab_5.Title = '计算';
            app.GridLayout6 = uigridlayout(app.Tab_5);
            app.Panel_2 = uipanel(app.GridLayout6);
            app.Panel_2.Title = '参数';
            app.GridLayout7 = uigridlayout(app.Panel_2);
            app.mmL = uilabel(app.GridLayout7);
            app.mmL.Text = 'mm';
            app.EditField_3Label = uilabel(app.GridLayout7);
            app.EditField_3Label.Text = '宽度';
            app.Edit_tanL = uieditfield(app.GridLayout7, 'numeric');
            app.Edit_tanL.Value = 10;
            app.EditField_4Label = uilabel(app.GridLayout7);
            app.EditField_4Label.Text = '单侧长度';
            app.Edit_normL = uieditfield(app.GridLayout7, 'numeric');
            app.Edit_normL.Value = 3;
            app.mmLabel_8 = uilabel(app.GridLayout7);
            app.mmLabel_8.Text = 'mm';
            app.Panel_4 = uipanel(app.GridLayout6);
            app.Panel_4.Title = '配置';
            app.GridLayout5 = uigridlayout(app.Panel_4);
            app.IndexType = uibuttongroup(app.GridLayout5);
            app.IndexType.SelectionChangedFcn = createCallbackFcn(app, @IndexTypeSelectionChanged, true);
            app.CNIButton = uiradiobutton(app.IndexType);
            app.CNIButton.Text = 'CNI';
            app.CNIButton.Value = true;
            app.MTFButton = uiradiobutton(app.IndexType);
            app.MTFButton.Text = 'MTF';
            app.DeconvMethod = uibuttongroup(app.GridLayout5);
            app.DeconvMethod.SelectionChangedFcn = createCallbackFcn(app, @DeconvMethodSelectionChanged, true);
            app.B1 = uiradiobutton(app.DeconvMethod);
            app.B1.Text = '方法1';
            app.B1.Value = true;
            app.B2 = uiradiobutton(app.DeconvMethod);
            app.B2.Text = '方法2';
            app.B3 = uiradiobutton(app.DeconvMethod);
            app.B3.Text = '方法3';
            app.B4 = uiradiobutton(app.DeconvMethod);
            app.B4.Text = '原始';
            app.Panel_3 = uipanel(app.GridLayout6);
            app.Panel_3.Title = '区域';
            app.GridLayout8 = uigridlayout(app.Panel_3);
            app.ROIListBox = uilistbox(app.GridLayout8);
            app.ROIListBox.Items = {'测量区域1', '测量区域2', '测量区域3', '测量区域4'};
            app.ROIListBox.ValueChangedFcn = createCallbackFcn(app, @ROIListBoxValueChanged, true);
            app.ROIListBox.Value = '测量区域1';
            app.TextArea = uitextarea(app.GridLayout8);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'左侧：'; '右侧：'; '前侧：'; '后侧：'};
            app.btn_indexcal = uibutton(app.GridLayout6, 'push');
            app.btn_indexcal.ButtonPushedFcn = createCallbackFcn(app, @btn_indexcalPushed, true);
            app.btn_indexcal.Enable = 'off';
            app.btn_indexcal.Text = '计算指标';
            app.btn_SaveData = uibutton(app.GridLayout6, 'push');
            app.btn_SaveData.ButtonPushedFcn = createCallbackFcn(app, @btn_SaveDataButtonPushed, true);
            app.btn_SaveData.Enable = 'off';
            app.btn_SaveData.Text = '保存指标';
            app.btn_selectROI = uibutton(app.GridLayout6, 'push');
            app.btn_selectROI.ButtonPushedFcn = createCallbackFcn(app, @btn_selectROIPushed, true);
            app.btn_selectROI.Enable = 'off';
            app.btn_selectROI.Text = '选取区域';
            app.Panel_5 = uipanel(app.GridLayout6);
            app.Panel_5.Title = '计算细节';
            app.GridLayout9 = uigridlayout(app.Panel_5);
            app.PCheckBox = uicheckbox(app.GridLayout9);
            app.PCheckBox.Enable = 'off';
            app.PCheckBox.Text = 'P';
            app.SCheckBox = uicheckbox(app.GridLayout9);
            app.SCheckBox.Enable = 'off';
            app.SCheckBox.Text = 'S';
            app.ESFCheckBox = uicheckbox(app.GridLayout9);
            app.ESFCheckBox.Enable = 'off';
            app.ESFCheckBox.Text = 'ESF';
            app.MTFCheckBox = uicheckbox(app.GridLayout9);
            app.MTFCheckBox.Enable = 'off';
            app.MTFCheckBox.Text = 'MTF';
            app.dPCheckBox = uicheckbox(app.GridLayout9);
            app.dPCheckBox.Enable = 'off';
            app.dPCheckBox.Text = 'dP';
            app.d2PCheckBox = uicheckbox(app.GridLayout9);
            app.d2PCheckBox.Enable = 'off';
            app.d2PCheckBox.Text = 'd2P';
            app.LSFCheckBox = uicheckbox(app.GridLayout9);
            app.LSFCheckBox.Enable = 'off';
            app.LSFCheckBox.Text = 'LSF';
            app.s_plot = uiswitch(app.GridLayout9, 'slider');
            app.s_plot.Items = {'关闭', '开启'};
            app.s_plot.ValueChangedFcn = createCallbackFcn(app, @s_plotValueChanged, true);
            app.s_plot.Value = '关闭';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '方法1';
            app.GridLayout2 = uigridlayout(app.Tab_2);
            app.functiondeconvBlindLabel = uilabel(app.GridLayout2);
            app.functiondeconvBlindLabel.Text = 'function: deconvBlind';
            app.drop_psftype1 = uidropdown(app.GridLayout2);
            app.drop_psftype1.Items = {'gussian'};
            app.drop_psftype1.Value = 'gussian';
            app.DropDown_2Label = uilabel(app.GridLayout2);
            app.DropDown_2Label.Text = '点扩散函数';
            app.edit_psfr1 = uieditfield(app.GridLayout2, 'numeric');
            app.edit_psfr1.Value = 0.7;
            app.EditField_5Label = uilabel(app.GridLayout2);
            app.EditField_5Label.Text = '扩展半径';
            app.EditField_6Label = uilabel(app.GridLayout2);
            app.EditField_6Label.Text = '卷积核尺寸';
            app.edit_kersize1 = uieditfield(app.GridLayout2, 'numeric');
            app.edit_kersize1.Value = 11;
            app.EditField_7Label = uilabel(app.GridLayout2);
            app.EditField_7Label.Text = '降噪参数';
            app.edit_denoise1 = uieditfield(app.GridLayout2, 'numeric');
            app.edit_denoise1.Value = 2.5;
            app.DropDown_3Label = uilabel(app.GridLayout2);
            app.DropDown_3Label.Text = '降噪算法';
            app.DropDown_3 = uidropdown(app.GridLayout2);
            app.DropDown_3.Items = {'中值滤波', '双边滤波'};
            app.DropDown_3.Value = '中值滤波';
            app.btn_m1start = uibutton(app.GridLayout2, 'push');
            app.btn_m1start.ButtonPushedFcn = createCallbackFcn(app, @btn_m1startPushed, true);
            app.btn_m1start.Enable = 'off';
            app.btn_m1start.Text = '图像处理';
            app.btn_indexcal1 = uibutton(app.GridLayout2, 'push');
            app.btn_indexcal1.ButtonPushedFcn = createCallbackFcn(app, @btn_indexcal1ButtonPushed, true);
            app.btn_indexcal1.Enable = 'off';
            app.btn_indexcal1.Text = '计算指标';
            app.Switch1 = uiswitch(app.GridLayout2, 'slider');
            app.Switch1.Items = {'原始图像', '处理图像'};
            app.Switch1.ValueChangedFcn = createCallbackFcn(app, @Switch1ValueChanged, true);
            app.Switch1.Enable = 'off';
            app.Switch1.Value = '原始图像';
            app.psf1 = uipanel(app.GridLayout2);
            app.psf1.Title = '点扩散函数矩阵';
            app.btn_SaveImg1 = uibutton(app.GridLayout2, 'push');
            app.btn_SaveImg1.ButtonPushedFcn = createCallbackFcn(app, @btn_SaveImg1ButtonPushed, true);
            app.btn_SaveImg1.Enable = 'off';
            app.btn_SaveImg1.Text = '保存图像';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '方法2';
            app.GridLayout2_2 = uigridlayout(app.Tab_3);
            app.functiondeconvRegLabel = uilabel(app.GridLayout2_2);
            app.functiondeconvRegLabel.Text = 'function: deconvReg';
            app.Switch_2 = uiswitch(app.GridLayout2_2, 'slider');
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = '方法3';
            app.GridLayout2_3 = uigridlayout(app.Tab_4);
            app.functiondeconvLucyLabel = uilabel(app.GridLayout2_3);
            app.functiondeconvLucyLabel.Text = 'function: deconvLucy';
            app.Switch_3 = uiswitch(app.GridLayout2_3, 'slider');
            app.RightPanel = uipanel(app.GridLayout);
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'图像'; 'AI'; 'MTFv50'; 'MTFv10'; 'CNI'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [false true true true true];
            app.UITable.Enable = 'off';
            app.UITable.Visible = 'off';
            app.AcceptanceIndexToolv03CopyrightSONTULabel = uilabel(app.RightPanel);
            app.AcceptanceIndexToolv03CopyrightSONTULabel.Text = 'Acceptance Index Tool  v0.3 "Copyright@SONTU"';
            app.btn_close = uibutton(app.RightPanel, 'push');
            app.btn_close.ButtonPushedFcn = createCallbackFcn(app, @btn_closeButtonPushed, true);
            app.btn_close.Text = '收起数据';
            app.btn_open = uibutton(app.RightPanel, 'push');
            app.btn_open.ButtonPushedFcn = createCallbackFcn(app, @btn_openButtonPushed, true);
            app.btn_open.Text = '显示数据';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AIndexTool

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end